classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Menu              matlab.ui.container.Menu
        Menu2             matlab.ui.container.Menu
        Filter_path       matlab.ui.control.TextArea
        Signal_path       matlab.ui.control.TextArea
        Filter_bt         matlab.ui.control.Button
        Choose_signal_bt  matlab.ui.control.Button
        Choose_filter_bt  matlab.ui.control.Button
        Raw_signal_ax     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        signal_file
        filter_file
        raw_signal % Description
        filtered_signal
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Choose_filter_bt
        function Choose_filter_btButtonPushed(app, event)

            [app.filter_file,filter_path] = uigetfile('*.fcf')  %open a fcf file
            app.Filter_path.Value = fullfile(filter_path, app.filter_file); 
        end

        % Callback function: Choose_signal_bt, Raw_signal_ax
        function Choose_signal_btButtonPushed(app, event)
            [app.signal_file,signal_path] = uigetfile('*.txt')  %open a fcf file
            app.Signal_path.Value = fullfile(signal_path, app.signal_file);
            app.raw_signal = load(app.signal_file)
            plot(app.Raw_signal_ax,app.raw_signal)
            hold(app.Raw_signal_ax, 'on')

        end

        % Button pushed function: Filter_bt
        function Filter_btButtonPushed(app, event)
            filter_coeff=load(app.filter_file)
            app.filtered_signal=filter(filter_coeff,1,app.raw_signal)
            plot(app.Raw_signal_ax,app.filtered_signal)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.Raw_signal_ax = uiaxes(app.UIFigure);
            title(app.Raw_signal_ax, 'Title')
            xlabel(app.Raw_signal_ax, 'X')
            ylabel(app.Raw_signal_ax, 'Y')
            zlabel(app.Raw_signal_ax, 'Z')
            app.Raw_signal_ax.ButtonDownFcn = createCallbackFcn(app, @Choose_signal_btButtonPushed, true);
            app.Choose_filter_bt = uibutton(app.UIFigure, 'push');
            app.Choose_filter_bt.ButtonPushedFcn = createCallbackFcn(app, @Choose_filter_btButtonPushed, true);
            app.Choose_filter_bt.Text = 'Выбрать фильтр';
            app.Choose_signal_bt = uibutton(app.UIFigure, 'push');
            app.Choose_signal_bt.ButtonPushedFcn = createCallbackFcn(app, @Choose_signal_btButtonPushed, true);
            app.Choose_signal_bt.Text = 'Выбрать сигнал';
            app.Filter_bt = uibutton(app.UIFigure, 'push');
            app.Filter_bt.ButtonPushedFcn = createCallbackFcn(app, @Filter_btButtonPushed, true);
            app.Filter_bt.Text = 'Filter';
            app.Signal_path = uitextarea(app.UIFigure);
            app.Filter_path = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end